classdef nanoFeatures < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FiltersTab                      matlab.ui.container.Tab
        CloseButton                     matlab.ui.control.Button
        ExtraanalysisPanel              matlab.ui.container.Panel
        qPaintCheckBox                  matlab.ui.control.CheckBox
        SilhouettemetricCheckBox        matlab.ui.control.CheckBox
        FiltersPanel                    matlab.ui.container.Panel
        ChannelAlignmentCheckBox        matlab.ui.control.CheckBox
        InputtypeDropDown               matlab.ui.control.DropDown
        InputtypeLabel                  matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        InputfileEditField              matlab.ui.control.EditField
        InputfileEditField_5Label       matlab.ui.control.Label
        ParametersTab                   matlab.ui.container.Tab
        RunButton                       matlab.ui.control.Button
        CloseButton_2                   matlab.ui.control.Button
        FiducialalignmentPanel          matlab.ui.container.Panel
        MaximuminterfiducialdistancenmEditField  matlab.ui.control.NumericEditField
        MaximuminterfiducialdistancenmEditFieldLabel  matlab.ui.control.Label
        FilteringPanel                  matlab.ui.container.Panel
        MaximumpointsEditField          matlab.ui.control.NumericEditField
        MaximumpointsEditFieldLabel     matlab.ui.control.Label
        MininterclusterdistancenmEditField  matlab.ui.control.NumericEditField
        MininterclusterdistancenmEditFieldLabel  matlab.ui.control.Label
        DesiredaspectratioEditField     matlab.ui.control.NumericEditField
        AspectratioLabel                matlab.ui.control.Label
        MaximumaspectratioEditField     matlab.ui.control.NumericEditField
        MaximumaspectratioEditFieldLabel  matlab.ui.control.Label
        ParticlesizecheckPanel          matlab.ui.container.Panel
        FractionoflocalizationswithinfittedsphereLabel  matlab.ui.control.Label
        RadiusthresholdEditField        matlab.ui.control.NumericEditField
        RadiusthresholdEditFieldLabel   matlab.ui.control.Label
        RadiushighlimitnmEditField      matlab.ui.control.NumericEditField
        RadiushighlimitnmEditFieldLabel  matlab.ui.control.Label
        RadiuslowlimitnmEditField       matlab.ui.control.NumericEditField
        RadiuslowlimitnmEditFieldLabel  matlab.ui.control.Label
        DBSCANClusteringPanel           matlab.ui.container.Panel
        MinimumpointsEditField          matlab.ui.control.NumericEditField
        MinimumpointsEditFieldLabel     matlab.ui.control.Label
        ScanningdiameternmEditField     matlab.ui.control.NumericEditField
        ScanningdiameternmEditFieldLabel  matlab.ui.control.Label
        qPAINTTab                       matlab.ui.container.Tab
        RunButton_2                     matlab.ui.control.Button
        CloseButton_4                   matlab.ui.control.Button
        GiveinformationperchannelPanel  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Channel12bEditField             matlab.ui.control.NumericEditField
        Channel12bEditFieldLabel        matlab.ui.control.Label
        Channel12EditField              matlab.ui.control.NumericEditField
        CheckBoxChannel12               matlab.ui.control.CheckBox
        Channel12EditFieldLabel         matlab.ui.control.Label
        Channel11bEditField             matlab.ui.control.NumericEditField
        Channel11bEditFieldLabel        matlab.ui.control.Label
        Channel11EditField              matlab.ui.control.NumericEditField
        CheckBoxChannel11               matlab.ui.control.CheckBox
        Channel11EditFieldLabel         matlab.ui.control.Label
        Channel10bEditField             matlab.ui.control.NumericEditField
        Channel1bEditField_2Label_3     matlab.ui.control.Label
        Channel10EditField              matlab.ui.control.NumericEditField
        CheckBoxChannel10               matlab.ui.control.CheckBox
        Channel10EditFieldLabel         matlab.ui.control.Label
        Channel9bEditField              matlab.ui.control.NumericEditField
        Channel1bEditField_2Label_2     matlab.ui.control.Label
        Channel9EditField               matlab.ui.control.NumericEditField
        CheckBoxChannel9                matlab.ui.control.CheckBox
        Channel9EditFieldLabel          matlab.ui.control.Label
        Channel8bEditField              matlab.ui.control.NumericEditField
        Channel1bEditField_3Label_5     matlab.ui.control.Label
        Channel8EditField               matlab.ui.control.NumericEditField
        CheckBoxChannel8                matlab.ui.control.CheckBox
        Channel8EditFieldLabel          matlab.ui.control.Label
        Channel7bEditField              matlab.ui.control.NumericEditField
        Channel1bEditField_3Label_4     matlab.ui.control.Label
        Channel7EditField               matlab.ui.control.NumericEditField
        CheckBoxChannel7                matlab.ui.control.CheckBox
        Channel7EditFieldLabel          matlab.ui.control.Label
        Channel6bEditField              matlab.ui.control.NumericEditField
        Channel1bEditField_3Label_3     matlab.ui.control.Label
        Channel6EditField               matlab.ui.control.NumericEditField
        CheckBoxChannel6                matlab.ui.control.CheckBox
        Channel6EditFieldLabel          matlab.ui.control.Label
        Channel5bEditField              matlab.ui.control.NumericEditField
        Channel1bEditField_3Label_2     matlab.ui.control.Label
        Channel5EditField               matlab.ui.control.NumericEditField
        CheckBoxChannel5                matlab.ui.control.CheckBox
        Channel5EditFieldLabel          matlab.ui.control.Label
        Channel4bEditField              matlab.ui.control.NumericEditField
        Channel4bLabel                  matlab.ui.control.Label
        Channel4EditField               matlab.ui.control.NumericEditField
        CheckBoxChannel4                matlab.ui.control.CheckBox
        Channel4EditFieldLabel          matlab.ui.control.Label
        Channel3bEditField              matlab.ui.control.NumericEditField
        Channel3bLabel                  matlab.ui.control.Label
        Channel3EditField               matlab.ui.control.NumericEditField
        CheckBoxChannel3                matlab.ui.control.CheckBox
        Channel3EditFieldLabel          matlab.ui.control.Label
        Channel2bEditField              matlab.ui.control.NumericEditField
        Channel2EditField               matlab.ui.control.NumericEditField
        Channel2bLabel                  matlab.ui.control.Label
        CheckBoxChannel2                matlab.ui.control.CheckBox
        Channel2EditFieldLabel          matlab.ui.control.Label
        Channel1bEditField              matlab.ui.control.NumericEditField
        Channel1EditField               matlab.ui.control.NumericEditField
        Channel1bEditFieldLabel         matlab.ui.control.Label
        CheckBoxChannel1                matlab.ui.control.CheckBox
        Channel1EditFieldLabel          matlab.ui.control.Label
        C_iMLabel                       matlab.ui.control.Label
        K_onM1S1Label                   matlab.ui.control.Label
        OptionsPanel                    matlab.ui.container.Panel
        ExposuretimemsEditField         matlab.ui.control.NumericEditField
        ExposuretimemsEditFieldLabel    matlab.ui.control.Label
        NumberofchannelsEditField       matlab.ui.control.NumericEditField
        NumberofchannelsEditFieldLabel  matlab.ui.control.Label
        FramesthresholdtomergeEditField  matlab.ui.control.NumericEditField
        FramesthresholdtomergeEditFieldLabel  matlab.ui.control.Label
        InitialframescutEditField       matlab.ui.control.NumericEditField
        InitialframescutEditFieldLabel  matlab.ui.control.Label
        SpecificityFilterCheckBox       matlab.ui.control.CheckBox
        GraphsTab                       matlab.ui.container.Tab
        CloseButton_3                   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        LocNPTab                        matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        NPdiamTab                       matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        DiamlocsTab                     matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        NPTdTab                         matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        file = ''; % global variable for the file name
        path = ''; % global variable for the file path
        numFiles = ''; % global variable for the number of files input by the user
        dataMerge; % global variable for the merged coords
        av; % global variable for the average coordinates correction in case of channel alignment
    end

    methods (Access = private)

        function output = readFile(app, inputType,filePath,i) %idk if the result could be already the app property
            switch inputType
                case 'NIKON'
                    disp('Importing N-STORM data...');

                    data=tdfread(filePath); % Read tab delimited file, returns a struct

                    try
                        channel_names = strings([length(data.Channel_Name),1]);
                    catch
                        %Do nothing if the struct is to big to pre-allocate
                        %(it works later)
                    end

                    for i=1:length(data.Channel_Name)
                        if (string(data.Channel_Name(i,:)) == "Bead Drift Correction")
                            channel_names(i) = "-1"; %Set as the drift correction channel
                        else
                            channel_names(i) = strtrim(string(data.Channel_Name(i,:))); % Transform channel names from chars to a string and remove white spaces
                        end
                    end
                    disp("Bead Drift Correction channel has been renamed to -1");

                    if app.numFiles.Value~=1 && exist('A',"var") % if there's more than one file we would need to concat and it exists
                        if exist('tmp',"var")
                            tmp = [tmp;A]; %if it exist, append
                        else
                            tmp = A; %temporal file to save the previous channel
                        end
                    end

                    n=length(data.Channel_Name);
                    A=zeros(n,4);
                    if (i<app.numFiles.Value && app.ChannelAlignmentCheckBox.Value == 1) %if it's not the last channel and channel alignment filter activated --> correct coordinates
                        disp("Cumulative average X:");
                        disp(sum(app.av(i:end,1)));
                        disp("Cumulative average Y:");
                        disp(sum(app.av(i:end,2)));

                        A(:,1)=data.X + sum(app.av(i:end,1));
                        A(:,2)=data.Y + sum(app.av(i:end,2));
                        A(:,3)=data.Frame;
                        A(:,4) = channel_names;

                    else
                        A(:,1)=data.X; %x
                        A(:,2)=data.Y;
                        A(:,3)=data.Frame;
                        A(:,4)=channel_names; % channel name
                    end
                    if exist('tmp',"var")
                        disp("Concatenating files");
                        output = [tmp;A]; %concat to previous file
                    else
                        output = A;
                    end

                case 'ONI'
                    disp('Importing ONI data...');

                    data=readmatrix(filePath);
                    data(:,2)=data(:,2)+1;          % Change starting frame from 0 to 1

                    if app.numFiles.Value~=1 && exist('A',"var") % if there's more than one file we would need to concat and it exists
                        if exist('tmp',"var")
                            tmp = [tmp;A]; %if it exist, append
                        else
                            tmp = A; %temporal file to save the previous channel
                        end
                    end

                    n=length(data);
                    A=zeros(n,4);
                    if (i<app.numFiles.Value && app.ChannelAlignmentCheckBox.Value == 1) %if it's not the last channel and channel alignment filter activated --> correct coordinates
                        disp("Cumulative average X:");
                        disp(sum(app.av(i:end,1)));
                        disp("Cumulative average Y:");
                        disp(sum(app.av(i:end,2)));

                        A(:,1)=data(:,3) + sum(app.av(i:end,1)); %x
                        A(:,2)=data(:,4) + sum(app.av(i:end,2)); %y
                        A(:,3)=data(:,2); %t
                        A(:,4)=cast(data(:,1),'uint8'); % channel name
                    else
                        A(:,1)=data(:,3); %x
                        A(:,2)=data(:,4);
                        A(:,3)=data(:,2);
                        A(:,4)=cast(data(:,1),'uint8'); % channel name
                    end
                    if exist('tmp',"var")
                        disp("Concatenating files");
                        output = [tmp;A]; %concat to previous file
                    else
                        output = A;
                    end
                case 'ThunderSTORM'
                    disp('Importing ThunderSTORM data...');

                    % User can choose which data to export into the csv, thus, the reader goes by column name instead of column number
                    data = readtable(filePath); % ThunderSTORM column names (matlab adapted): 'frame'    'x_nm_'    'y_nm_' | original:    'x [nm]'    'y [nm]'
                    data.Properties.VariableNames = lower(data.Properties.VariableNames); %Change names to lowercase to avoid case-sensitive errors.

                    n = height(data); % Get size of the matrix
                    A = zeros(n,4); % creates empty matrix with the size of the data
                    if(i<app.numFiles.Value && app.ChannelAlignmentCheckBox.Value == 1) %if it's not the last channel and channel alignment filter activated --> correct coordinates
                        disp("Cumulative average X:");
                        disp(sum(app.av(i:end,1)));
                        disp("Cumulative average Y:");
                        disp(sum(app.av(i:end,2)));

                        A(:,1)=data.x_nm_ + sum(app.av(i:end,1)); %x
                        A(:,2)=data.y_nm_ + sum(app.av(i:end,2)); %y
                        A(:,3)=data.frame; %t
                        A(:,4)=i*ones(height(data),1)-1; % channel name (id) --> Creates a column with the value in i (file that is reading at the moment) minus 1 to match the original ONI naming (0, 1)
                    else % if it's the last channel we don't correct the coordinates
                        A(:,1)=data.x_nm_; % X
                        A(:,2)=data.y_nm_; % Y
                        A(:,3)=data.frame; % T
                        if (any(ismember(data.Properties.VariableNames,'channel')) && app.ChannelAlignmentCheckBox.Value == 0) %if the table contains the channel info column and we don't align channels
                            A(:,4)=data.channel; %  channel name (id)
                        else %Otherwise we assign the channel name
                            A(:,4)=i*ones(height(data),1)-1; %  channel name (id)
                        end
                    end
                    if exist('tmp',"var") % Concat to file if it already exists
                        disp("Concatenating files");
                        output = [tmp;A];
                    else
                        output = A; % First time, only one channel, no need to concat
                    end
            end
        end

        function channelAlignment(app) % Returns average array
            disp('Channel alignment filter...');

            %%% READ FIDUCIALS FILES %%%%
            disp('Reading fiducials files...');
            title = "Select 1 FOV of fiducials for each channel";
            fidCoords = {}; %Create empty cell array to save the distances calculated in each field of view
            [fidFile,fidPath] = uigetfile('*.*',title,'MultiSelect', 'on'); %open dialog to select all kind of files (all extensions)

            for i=1:length(fidFile) %save fiducial coordinates for all 3 channels
                fidCoords{i} = readmatrix(char(strcat(fidPath, fidFile(:,i)))); %Save fiducials coordinates in the cell array.
                fidCoords{i} = rmmissing(fidCoords{i}); %Remove NaN values
            end

            %%% CLUSTER FIDUCIALS %%%
            set(groot,'defaultFigureVisible','off') % Turn off figure visibility
            for i=1:length(fidCoords)
                %Re-organize fiducial file's columns to match cluster1
                n=length(fidCoords{i});
                A=zeros(n,3);
                A(:,1)=fidCoords{i}(:,2);
                A(:,2)=fidCoords{i}(:,3);
                A(:,3)=fidCoords{i}(:,1);
                save XYTfids.txt A -ascii % Save fiducial locs in a text file for cluster1 to read
                [~,~,~,massCenter{i}] = Cluster1('XYTfids.txt',80,10,100); %Call to cluster 1 for each fiducials file
                massCenter{i} = massCenter{i}.'; % Tranpose
            end
            set(groot,'defaultFigureVisible','on') % Turn on figure visibility

            %%% MATCH FIDUCIALS %%%
            disp('Matching fiducials...');
            dist = cell(massCenter(:,2:end)); % init distance cell array
            app.av = []*(length(fidFile)-1); % init averages distances matrix
            for p=2:length(fidFile) %loop number of channels (starting in the second channel)
                % Find nearest neighbors and calculate distances
                for i=1:size(massCenter{p},1) %Loop through all points in channel i as reference for the previous channel
                    k = dsearchn(massCenter{p-1}, [massCenter{p}(i,1),massCenter{p}(i,2)]);
                    dist{p-1}(i,1) = massCenter{p}(i,1) - massCenter{p-1}(k,1); %X distance
                    dist{p-1}(i,2) = massCenter{p}(i,2) - massCenter{p-1}(k,2); %Y distance

                    if sqrt(dist{p-1}(i,1)^2 + dist{p-1}(i,2)^2) > app.MaximuminterfiducialdistancenmEditField.Value
                        dist{p-1}(i,1) = NaN;
                        dist{p-1}(i,2) = NaN;
                    end
                end

                % Discard outliers
                dist_filt = rmmissing(dist{p-1});
                dist_filt = rmoutliers(dist_filt); % Outliers in distance
                disp(strcat([num2str(size(dist{p-1},1)-size(dist_filt,1)) ' fiducial matchings are outliers and have been removed']));

                % Average
                if (length(dist_filt(:,1)) > 1)
                    app.av(p-1,1) = mean(dist_filt(:,1)); % Average of the X coordinates distances
                    app.av(p-1,2) = mean(dist_filt(:,2)); % Average of the Y coordinates distances
                else
                    app.av(p-1,1) = dist_filt(1,1); % Only X coordinates distance
                    app.av(p-1,2) = dist_filt(1,2); % Only Y coordinates distance
                end
            end
        end

        function [idx, sections, clustMembsCell647] = clustering(app, file, silhouetteFlag)
            disp('Reading data...');

            %%%%% Clustering =====================================================

            Pts = [app.dataMerge(:,1) app.dataMerge(:,2)]; % Create Point matrix

            %%%% Debugger section: comment when not needed %%%
            figure('name', plus('Raw coordinates in file: ', file))
            hold on
            scatter(Pts(:,1),Pts(:,2), '.'); % Plot original clusters
            drawnow % Update figure real-time so the user doesn't have to wait for the end of the execution to see
            hold off
            %%%%%

            %%% Crop image into smaller sections %%%

            % Get image edges
            minX = min(Pts(:,1));
            maxX = max(Pts(:,1));
            minY = min(Pts(:,2));
            maxY = max(Pts(:,2));

            % Calculate total area
            totX = maxX - minX;
            totY = maxY - minY;

            % Calculate sections XY limits (9-section crop, starting top-left)
            sections = zeros(length(Pts), 9);

            sections(:,1) = Pts(:,1) < (minX + totX/3)                                  & Pts(:,2) > (minY + 2*(totY/3)); % Get index for coordinates in Pts inside the section
            sections(:,2) = Pts(:,1) > (minX + totX/3) & Pts(:,1) < (minX + 2*(totX/3)) & Pts(:,2) > (minY + 2*(totY/3));
            sections(:,3) =                              Pts(:,1) > (minX + 2*(totX/3)) & Pts(:,2) > (minY + 2*(totY/3));

            sections(:,4) = Pts(:,1) < (minX + totX/3)                                  & Pts(:,2) > (minY + totY/3) & Pts(:,2) < (minY + 2*(totY/3));
            sections(:,5) = Pts(:,1) > (minX + totX/3) & Pts(:,1) < (minX + 2*(totX/3)) & Pts(:,2) > (minY + totY/3) & Pts(:,2) < (minY + 2*(totY/3));
            sections(:,6) =                              Pts(:,1) > (minX + 2*(totX/3)) & Pts(:,2) > (minY + totY/3) & Pts(:,2) < (minY + 2*(totY/3));

            sections(:,7) = Pts(:,1) < (minX + totX/3)                                  & Pts(:,2) < (minY + totY/3);
            sections(:,8) = Pts(:,1) > (minX + totX/3) & Pts(:,1) < (minX + 2*(totX/3)) & Pts(:,2) < (minY + totY/3);
            sections(:,9) =                              Pts(:,1) > (minX + 2*(totX/3)) & Pts(:,2) < (minY + totY/3);

            sections = logical(sections); % Convert to logical

            %%%% Clusterig using DBSCAN
            disp('Clustering using DBSCAN...');
            tic
            idx = {}; % Create cell array to save DBSCAN results (Each section is a different size so we can't use a matrix)
            epsilon = app.ScanningdiameternmEditField.Value; % pre-allocate variables as the parallel toolbox cannot access the local app variables
            minPts = app.MinimumpointsEditField.Value;
            PtsSect = cell(size(sections,2),1); % Pre-allocate cell array to save the coordinates in each section
            for s=1:(size(sections,2))
                PtsSect{s} = Pts(sections(:,s),:); %Pre-allocate coordinates
            end

            % Create parallel pool
            cores = maxNumCompThreads; % Get the maximum number of computational threads
            if cores > 9
                threads = 9; % If there are more cores than sections, we keep the number of threads as number of sections to parallelize
            else
                threads = cores; % Otherwise we use as many cores as we have available
            end

            delete(gcp('nocreate')); % Deletes any parallel pool created in order to avoid errors.

            parpool(threads); % Initialize parallel pool
            parfor s=1:(size(sections,2))
                idx{s} = dbscan(PtsSect{s},epsilon,minPts); % Parse points filtering by section | output: cluster indices for each observation
                disp(strcat(['Found ' num2str(length(unique(idx{s}))) ' clusters in section ' num2str(s)]));
            end
            if silhouetteFlag == 1 % Silhouette metric checkbox
                for s=1:(size(sections,2))
                    figure('name',strcat(['Silhouette metrics for section ' num2str(s)]))
                    hold on
                    silhouette(PtsSect{s}, idx{s}); %Plot cluster silhouettes for each point in each cluster (from -1 to 1)
                    drawnow % Update figure real-time so the user doesn't have to wait for the end of the execution to see
                    hold off
                end
            end
            toc
        end

        function [name_channels, locs4Clust_filtered, headers, outm] = qualityFilters(app, idx, sections,filename)

            %%% Filter by localization number and elongation ==========================

            disp('Calculating cluster features...');

            nClust = zeros(size(sections,2),1); % Pre-allocate array to save number of cluster per section
            sectionCoords = cell(size(sections,2),1); % Pre-allocate cell array to save the coordinates in each section
            locs4Clust = cell(length(nClust),size(sections,2)); %Pre-allocate cell array to save coordinates per cluster
            elongation = cell(size(sections,2),1); % initialize cell to save aspect ratio
            validClust = cell(1,size(sections,2)); % Init logical cell array to flag valid clusters
            idxValidClust = cell(1,size(sections,2)); % Init cell array to save valid cluster indexes
            isolatedClust = cell(1,size(sections,2)); % Init cell array
            clustCent = cell(1,size(sections,2)); % Init cell array
            locs4Clust_filtered = cell(1,size(sections,2)); % Init cell array
            channels = []; % Create empty array to save the channels ID from different cluster localizations (in case in the first cluster we are unlucky and only have 1 channel)

            for s=1:(size(sections,2)) %Gets number of columns (sections)
                nClust(s) = length(unique(idx{s})); % Number of clusters
                %Group localizations by cluster
                sectionCoords{s} = app.dataMerge(sections(:,s),:); %Save coordinates for each section
                for c=1:nClust(s)-1 %One less because nClust also includes cluster -1 for outliers
                    locs4Clust{c,s} = sectionCoords{s}(idx{s}==c,:); % Save coordinates per cluster
                    meanTemp = mean(locs4Clust{c,s}); %Calculate cluster centers
                    clustCent{s}(c,:) = [meanTemp(1),meanTemp(2)];
                end

                %Flag isolated particles
                try
                    D = pdist2(clustCent{s}(:,1),clustCent{s}(:,2),'euclidean','Smallest',2); %calculate the 2 smallest distances (the smallest is 0, because comparing vector with itself)
                    isolatedClust{s} = D(2,:) >= app.MininterclusterdistancenmEditField.Value;
                catch
                    warning("No isolated clusters, going to the next image section...");
                    continue; % Go to next loop iteration
                end

                disp('Filtering...');

                idxValidClust{s} = false(nClust(s)-1,1); % init logical array to flag valid clusters
                validClust{s} = 1:1:nClust(s)-1; % intialization cluster indexes
                r=0;

                for i = 1 : nClust(s)-1 %loop to screen all clusters
                    if(size(locs4Clust{i,s},1) >= app.MinimumpointsEditField.Value) && (size(locs4Clust{i,s},1) <= app.MaximumpointsEditField.Value) %aspect ratio filter activated
                        % Filter particle based on ellipse elongation & major axis length
                        ellipse_t = fit_ellipse_app(locs4Clust{i,s}(:,1),locs4Clust{i,s}(:,2),app.DesiredaspectratioEditField.Value,app.MaximumaspectratioEditField.Value,app.RadiushighlimitnmEditField.Value*2, isolatedClust{s}(i));
                        valid = ellipse_t.valid;
                        if valid == 1
                            idxValidClust{s}(i)=true; % flag as true the valid cluster (by localiz number and elongation)
                            elongation{s}(i,1) = ellipse_t.long_axis/ellipse_t.short_axis; % Save aspect ratio
                        end
                    else
                        disp(strcat( ['#' num2str(i) ' cluster has few localizations and has been excluded']));
                        r=r+1; %count discarded clusters
                    end
                end
                %filter indexes and centers by localizations and elongation
                validClust{s} = validClust{s}(idxValidClust{s});
                clustCent{s} = clustCent{s}(idxValidClust{s},:);
                elongation{s} = elongation{s}(idxValidClust{s});
                locs_tmp = locs4Clust(:,s); %Temporal var to save the clusters for section s
                locs4Clust_filtered{:,s} = locs_tmp(idxValidClust{s}); % Filter valid clusters and save in new cell array

                % Calculate number of channels to organize the output
                for i=1:length(locs4Clust_filtered{s})
                    channels = [channels;locs4Clust_filtered{s}{i}(:,4)]; % Concat the channel IDs from each cluster
                end

                disp(strcat( [num2str(nClust(s)-1-length(validClust{s})-r) ' clusters have been excluded by fit_ellipse due to unrealistic size or elongation']));
            end

            name_channels = unique(channels); % Save unique channel IDs
            clearvars locs4Clust locs_tmp % Clear unfiltered cell arrays to save memory space

            % Initialize output file:
            try
                headers = ["Diameter","Aspect ratio","X coordinate","Y coordinate","Cluster localizations"]; % Array with the headers for the features output
                for i=1:numel(name_channels)
                    featureNames = sprintf('channel %s localizations', num2str(i));
                    headers = [headers, featureNames];
                end
                % outFilename = sprintf('features_%s.csv', filename);
                % writematrix(headers, outFilename); % Initialize output csv file with headers
            catch
                warning("No clusters were found in your image, finishing execution...")
                figure('name', plus('Original detected clusters by DBSCAN in file: ', filename))
                gscatter(sectionCoords{1}(:,1),sectionCoords{1}(:,2),idx{1}); % Plot original clusters
                drawnow % Update figure real-time so the user doesn't have to wait for the end of the execution to see
                return; % finish app execution
            end

            %%% Size Check
            %%% ===================================================================================
            %this performs a solid-sphere circle-fitting of the clusters to calculate the radius
            %comprising a fraction of the localization, NPs with unrealistic size are discarded

            center = cell(size(sections,2),1);
            radius = cell(size(sections,2),1);
            rCheck = cell(size(sections,2),1);


            outm = [];

            for s=1:(size(sections,2)) %Gets number of columns (sections)
                idx2remove{s}=true(length(validClust{s}),1); % logical array to remove the index of discarded clusters
                discard=0; % flag to discard a cluster

                for i=1:length(validClust{s}) %loop to screen all previously filtered clusters
                    actualClust = locs4Clust_filtered{s}(i,1); % Retrieve cluster data from nested cell array
                    clusterCoords = actualClust{1}(:,1:2); % temporal var to extract XY coords of each cluster
                    iniCenter = [mean(clusterCoords(:,1)) mean(clusterCoords(:,2))]; % mean coord as initial center guess
                    covMat = cov(clusterCoords); % calculate covariance matrix
                    iniRadius = 1.5*mean([sqrt(covMat(1,1)) sqrt(covMat(2,2))]); % initial guess of radius (1.5x the stDev)

                    % Now the location of the center of the smallest sphere encompassing p% of the datapoints is determined.
                    CenterSampleSizeAz = 10;
                    CenterSampleSizeRad = 4;
                    CenterLocation = zeros(CenterSampleSizeAz+1,2,CenterSampleSizeRad);
                    TrackLocNumber = zeros(CenterSampleSizeAz+1,CenterSampleSizeRad);

                    for m=1:CenterSampleSizeRad % screen circles of increasing radius
                        t2 = linspace(0,2*pi,CenterSampleSizeAz);
                        XCurrentCircle = 0.05*m*iniRadius*cos(t2)+iniCenter(1); % Xcoords of an approx circle with initial center and increasing radius
                        YCurrentCircle = 0.05*m*iniRadius*sin(t2)+iniCenter(2); % Ycoords of an approx circle with initial center and increasing radius
                        for o1=1:CenterSampleSizeAz % screen angular directions
                            CenterLocation(o1,:,m) = [XCurrentCircle(o1) YCurrentCircle(o1)]; % coordinate of each (XY) point of the circle, different angular directions
                            LocDistCheck = find(((clusterCoords(:,1)-XCurrentCircle(o1)).^2+(clusterCoords(:,2)-YCurrentCircle(o1)).^2) < iniRadius^2); % for each point (radius and angular direct), see if better than intial radius
                            TrackLocNumber(o1,m) = length(LocDistCheck); % for each (radius and angular dir): how many local of cluster are closer if compared to initial rad
                        end
                    end

                    RefLocCheck = find(((clusterCoords(:,1)-iniCenter(1)).^2+(clusterCoords(:,2)-iniCenter(2)).^2) < iniRadius^2); % find distances from initial guess center shorter than initial guess radius
                    RefLocNumber = length(RefLocCheck); % score of reference: how many local of cluster within initial guess radius
                    MaxLoc = max(max(TrackLocNumber)); % max score for each point (m-radius and o1-angular dir) previously screeened
                    if MaxLoc > RefLocNumber % if better than initial guess
                        [I,J] = find(TrackLocNumber==MaxLoc); % find the indexes corresponding to angular direction (o1) and radius (m) screened having max score
                        MaxLocMinRadTemp = [I J]; % store the indexes of the angular dir and radius
                        MaxLocMinRadInd = (J==min(J)); % find(J==min(J)); % logical label the indexes (o1-angular dir and m-radius) with smallest radius (m)
                        MaxLocMinRad = MaxLocMinRadTemp(MaxLocMinRadInd,:); % filter to select indexes (o1-angular dir and m-radius) with smallest radius (m)
                        finalCenter = mean(CenterLocation(MaxLocMinRad(:,1),:,min(J)),1); % move the circle center in the new position
                    else
                        finalCenter = iniCenter;
                    end

                    RadiusVec = linspace(0,3*iniRadius,1500);
                    RadiusNumLoc = zeros(length(RadiusVec),1);
                    for p=1:length(RadiusVec) % more accurate screen of radius
                        LocDistCheckRad = find(((clusterCoords(:,1)-finalCenter(1)).^2+(clusterCoords(:,2)-finalCenter(2)).^2) < RadiusVec(p)^2); % indexes of cluster localization within radius
                        RadiusNumLoc(p) = length(LocDistCheckRad); % for each screened radius a score: number of localizations within it
                    end

                    RadTrack = 1;
                    while RadTrack<size(RadiusNumLoc,1) && (RadiusNumLoc(RadTrack) < app.RadiusthresholdEditField.Value * size(clusterCoords,1)) % this loop find which of the screened radius has FractThresh of cluster localizations
                        RadTrack = RadTrack+1;
                    end
                    finalRadius = RadiusVec(RadTrack); % the final radius is set to the minimum comprising FractThresh of cluster localizations

                    % fore each cluster, set new values of center and radius
                    center{s}(i,:) = finalCenter';
                    rCheck{s}(i) = finalRadius;

                    % a final check that radius falls within a realistic range
                    if rCheck{s}(i) >= app.RadiuslowlimitnmEditField.Value && rCheck{s}(i) <= app.RadiushighlimitnmEditField.Value
                        radius{s}(i)=finalRadius;
                    else
                        disp(['Cluster #' num2str(i) ' from section #' num2str(s) ' has been excluded from the analysis due to an unrealistic size: ' num2str(round(rCheck{s}(i))*2) 'nm diameter.'])
                        idx2remove{s}(i) = false; % collect the indexes of the clusters to remove
                        discard=discard+1;
                    end
                end

                disp(strcat(['Identified ' num2str(length(validClust{s})-discard) ' valid nanoparticles from ' num2str(length(validClust{s})) ' candidate nanoparticles']));

                try
                    NPselect = radius{s}~=0; % Filter 0 values in the radius
                    radius{s} = radius{s}(NPselect);
                    center{s} = [center{s}(NPselect,1), center{s}(NPselect,2)];
                catch
                    warning("No nanoparticles identified in this section. Moving on to the next...");
                    continue;
                end

                clustCent{s} = clustCent{s}(idx2remove{s},:); % remove discarded clusters, after size check
                elongation{s} = elongation{s}(idx2remove{s}); % Remove discarded cluster from feature
                locs_tmp = locs4Clust_filtered(:,s); %Temporal var to save the clusters for section s
                locs4Clust_filtered{:,s} = locs_tmp{1}(idx2remove{s}); % Filter valid clusters and save in new cell array

                % Export calculated features
                disp('Creating output...');

                % Statistics and export:
                % csv: diameter | Elongation | Total localizations | (channel locs)

                diam{s} = radius{s}*2;

                % Calculate total localizations per cluster:
                locs_total = []; % Initialize and reset the temporal var for locs
                for i=1:length(locs4Clust_filtered{s}) % Loops for each cluster
                    tmp = locs4Clust_filtered{s}(i,1); % Extract cluster cell
                    locs_total(i) = length(tmp{1}); % Count the number of locs for each cluster
                end

                out = [diam{s}.',elongation{s},center{s}(:,1),center{s}(:,2),locs_total.']; % Start output matrix with the static data

                % Calculate localizations per cluster:
                for j=1:numel(name_channels) % Loops for each channel
                    locs_channel = []; % Initialize and reset the temporal var for locs
                    for i=1:length(locs4Clust_filtered{s}) % Loops for each cluster
                        tmp = locs4Clust_filtered{s}(i,1); % Extract cluster cell
                        locs_channel(i) = nnz(tmp{1}(:,4) == name_channels(j)); % Count the number of "non-zero elements" that equal 4 in the id column, for each cluster (channels are id 0-n and matlab counts from 1, so we need to substract
                    end
                    out = [out, locs_channel.']; % Add locs per channel to output
                end

                outm = [outm ; out];

            end % End of sections loop

            %%% Plot filtered clusters ============================================================
            % displays the selected clusters in only one of the sections (1)

            section = 2; % Change section to plot

            %%% Bit to plot the selected filters in only one section:
            %             try
            %                 figure('name',plus('Filtered clusters for section ', num2str(section), ' for file ' filename))
            %                 hold on
            %                 gscatter(sectionCoords{section}(:,1),sectionCoords{section}(:,2),idx{section}); % Plot original clusters
            %                 drawnow % Update figure real-time so the user doesn't have to wait for the end of the execution to see
            %                 plot(center{section}(:,1), center{section}(:,2), 'o', 'LineWidth',2, 'MarkerSize',20, 'Color', 'black'); % Mark the selected ones
            %                 for i = 1 : length(center{section})
            %                     txt1 = ['\leftarrow ' num2str(i)];
            %                     text(center{section}(i,1),center{section}(i,2),txt1) % this is to visualize the label of the identified clusters
            %                 end
            %                 legend('off'); % disable the legend
            %                 hold off
            %             catch
            %                 warning("No nanoparticles were identified in this section, finishing plotting.");
            %                 return;
            %             end

            %%% Plot clusters ======================================================================
            % displays the selected nanoparticles coloring the localizations based on the channel

            rainbow = [255,0,0;255,128,0;255,255,0;0,255,0;0,255,255;0,128,255;0,0,255;127,0,255;255,0,255;0,0,0]; %10-color colormap to display different channels
            cmap = colormap(uint8(rainbow));
            subset = cmap(1:numel(name_channels),:);

            figure('name',plus('Cluster localizations by channel for file ', filename))
            hold on
            for s=1:(size(sections,2))
                for i=1:length(locs4Clust_filtered{s})
                    scatter(locs4Clust_filtered{s}{i}(:,1), locs4Clust_filtered{s}{i}(:,2), 5, locs4Clust_filtered{s}{i}(:,4), 'filled');
                    colormap(gca,subset)
                    colorbar('Ticks', 1:numel(name_channels), 'TicksMode', 'manual', 'TickLabelsMode', 'manual', 'TickLabels', name_channels)
                end
            end
            legend_labels = cell(numel(unique(channels)),1);
            h = zeros(numel(name_channels), 1);
            for j = 1:numel(name_channels)
                legend_labels{j} = sprintf('channel %d', name_channels(j)); % Create legend labels
                h(j) = patch(NaN, NaN, subset(j,:)); % Create a patch (effectively, a fake graph) to get the legend for only #channels unique data points
            end
            legend(h, num2str(unique(channels)), 'Location', 'northeast', 'String', legend_labels);
            set(gca,'Color',uint8([192, 192, 192])) %Set plot backround to grey
            drawnow
            hold off
            savefig(plus('results/Cluster localizations by channel for file ', filename));

            %Graphical output in-app:
            locs_total = []; % Initialize and reset the temporal var for locs
            for i=1:length(locs4Clust_filtered{section})
                tmp = locs4Clust_filtered{section}(i,1); % Extract cluster cell
                locs_total(i) = length(tmp{1}); % Count the number of locs for each cluster
            end
            histogram(locs_total,30,'FaceColor','c','Parent', app.UIAxes4); %locs NP
            histogram(diam{section},10,'FaceColor','c','Parent', app.UIAxes5); %diam NP
            scatter(diam{section},locs_total,'filled',"cyan",'Parent', app.UIAxes6); %diam vs locs


            % Plot filtered clusters in full image
            try
                figure('name',plus('Filtered clusters for file ', filename))
                hold on
                itot = 0;
                for s=1:(size(sections,2))
                    gscatter(sectionCoords{s}(:,1),sectionCoords{s}(:,2),idx{s}); % Plot original clusters
                    plot(center{s}(:,1), center{s}(:,2), 'o', 'LineWidth',2, 'MarkerSize',20, 'Color', 'black'); % Mark the selected ones
                    for i = 1 : length(center{s})
                        txt1 = ['\leftarrow ' num2str(i+itot)];
                        text(center{s}(i,1),center{s}(i,2),txt1) % this is to visualize the label of the identified clusters
                    end
                    itot = itot + i;
                    legend('off'); % disable the legend
                end
                drawnow
                hold off
                savefig(plus('results/Filtered clusters for file ', filename))
            catch
                warning("Plotting of filtered full image failed for some reason :)");
            end
        end

        function [headers, outm] = qpaint(app, name_channels, locs4Clust_filtered, filename, headers, outm)
            disp("qPAINT analysis...");

            nChannels = numel(name_channels); %Get number of different channels based on naming
            allLocs4Clust = vertcat(locs4Clust_filtered{:}); %concat filtered clusters from all 9 sections vertically
            LocsPerChannel = cell(nChannels, 1); % preallocate cell array for each channel. LocsPerChannel is a cell with a cell per channel with a cell per cluster with an [x,y,t] double table.
            nNP = numel(allLocs4Clust); %get total number of filtered nanoparticles
            disp(strcat(['Total number of nanoparticles found before qPAINT:' num2str(nNP)]));
            for c = 1:nChannels % Loop to separate the localizations per channel
                LocsPerChannel{c} = cell(nNP,1); %Preallocate cell array for each channel
                for np = 1:nNP
                    % Get indices of localisations in NP that correspond to the current channel (logical values for the idx that belong to the current channel
                    channelIdx = allLocs4Clust{np}(:,4) == name_channels(c); %
                    % Extract the NP information for the current channel
                    channelLocs = allLocs4Clust{np}(channelIdx, :);
                    % Store the NP information for the current channel in a separate cell
                    LocsPerChannel{c}{np} = channelLocs(:, 1:3);
                end
            end

            % Init params
            nbins = 100;
            MaxFrames = app.FramesthresholdtomergeEditField.Value;
            %Note: you can either save "var = app.fieldX.Value" or save the pointer and then call them as "var(i).Value"
            Checks = [app.CheckBoxChannel1,app.CheckBoxChannel2,app.CheckBoxChannel3,app.CheckBoxChannel4,app.CheckBoxChannel5,app.CheckBoxChannel6,app.CheckBoxChannel7,app.CheckBoxChannel8,app.CheckBoxChannel9,app.CheckBoxChannel10,app.CheckBoxChannel11,app.CheckBoxChannel12];
            Ci = [app.Channel1EditField,app.Channel2EditField,app.Channel3EditField,app.Channel4EditField,app.Channel5EditField,app.Channel6EditField,app.Channel7EditField,app.Channel8EditField,app.Channel9EditField,app.Channel10EditField,app.Channel11EditField,app.Channel12EditField];
            Kon = [app.Channel1bEditField,app.Channel2bEditField,app.Channel3bEditField,app.Channel4bEditField,app.Channel5bEditField,app.Channel6bEditField,app.Channel7bEditField,app.Channel8bEditField,app.Channel9bEditField,app.Channel10bEditField,app.Channel11bEditField,app.Channel12bEditField];
            OutlierFiltCheckbox = true; % can be a parameter, but is currently a default option

            % initialize all matrices and counters to be used
            Nframes = max(app.dataMerge(:,3))+1; % MATLAB counts from frame 1...
            ClustLocMerged=cell(nNP,nChannels); %Merged locs per cluster for each channel
            ParticleTimeTraces=cell(nNP,nChannels);
            dark_times=cell(nNP,nChannels);
            ParticleTimeTracesCut=cell(nNP,nChannels);
            ParticleTimeTracesDark=cell(nNP,nChannels);
            ParticleTimeTracesBright=cell(nNP,nChannels);
            CDF=cell(nNP,nChannels);
            NormCDF=cell(nNP,nChannels);
            loc=zeros(nNP,nChannels);
            dark_mean=zeros(nNP,nChannels);
            dark_median=zeros(nNP,nChannels);
            emptyNP=0;
            noCDF=0;
            wrongShape=0;
            bright_times=cell(nNP,nChannels);
            bright_mean=zeros(nNP,nChannels);
            bright_median=zeros(nNP,nChannels);
            sd_dark=zeros(nNP,nChannels);
            sd_bright=zeros(nNP,nChannels);
            dark_mean_fitted=zeros(nNP,nChannels);
            Td_fit_conf=zeros(nNP,nChannels);
            qPaintVals = zeros(nNP,nChannels);
            SpecificFiltCnt = 0;

            fitfunCDF = fittype( @(b,x) 1-exp(-x/b)); %cdf fitting function

            for c = 1:nChannels %for each channel
                for np = 1:nNP %for each cluster (nanoparticle)
                    % Merge localizations if they're closer than MaxFrames
                    dataCluster=LocsPerChannel{c}{np}; %Get locs for cluster np in channel c
                    dataCluster(:,end+1)=zeros; %Add column of 0 at the end, to save the merge flag
                    for n = 1:size(dataCluster,1)-1 %Visit all locs
                        rframe=dataCluster(n,3);         % Reference frame
                        nframe=dataCluster(n+1,3);       % Next frame
                        if nframe==rframe                % If frames are equal label as 2
                            dataCluster(n,end)=2;        % [NOTE] multiple locs in the same cluster in the same frame are rare, the psf would likely overlap, so we want to get rid of them since it's a duplicate and the timetrace is binary. Thus, flag them with an ignored number.
                        elseif nframe-rframe<=MaxFrames+1  % If frames are closer than MaxFrames label as 1 (flag to merge)
                            dataCluster(n,end)=1;
                        end
                    end
                    %                     disp(length(find(dataCluster(:,4)==2))); % STIJN: print the number of flag==2 in the cluster (duplicated locs)

                    % Organize linked localizations in a new cell array (linked locs to merge are saved in the same cell array)
                    ClusLocLink=cell(nnz(~dataCluster(:,end)),1); %Init array to save the linked locs
                    r=1; %index for the merged locs array
                    for n = 1:size(dataCluster,1)
                        link=dataCluster(n,end); %get the flag from the array we just created (0==don't link | 1==link)
                        if link==0 % don't link
                            ClusLocLink{r,1}(end+1,1:3)=dataCluster(n,1:3); %save last linked loc in the current cell
                            r=r+1; %Go to next cell
                        elseif link==1 % link
                            ClusLocLink{r,1}(end+1,1:3)=dataCluster(n,1:3); %saves linked localizations in the same cell
                        end
                    end

                    % Merge linked localizations
                    for n = 1:length(ClusLocLink)
                        ClustLocMerged{np,c}(end+1,:) = ClusLocLink{n,1}(1,:); % Add first localization from event

                        if size(ClusLocLink{n,1},1)>1 % Fill rest of linked locs from events with 0
                            eventLength = ClusLocLink{n,1}(end,3) - ClusLocLink{n,1}(1,3); %(last frame in the linked cell) - (first frame)
                            ClustLocMerged{np,c}(end+1:end+eventLength,3) = (ClustLocMerged{np,c}(end,3)+1):(ClustLocMerged{np,c}(end,3)+eventLength); %fill frames with the event length with XY=00
                        end
                    end %For each cluster in each channel there's a cell containing the merged localization (first loc with XY coords and then 0s for the rest of locs in the event

                    if ~isempty(ClustLocMerged{np,c}) % if the cluster is empty, don't overwrite it
                        LocsPerChannel{c}{np} = ClustLocMerged{np,c}; %overwrite original locs with the merged locs (lots of 0s)
                    end

                    %  make binary time traces
                    ParticleTimeTraces{np,c}=zeros(Nframes,1); %init binary time trace with 0s
                    loc(np,c)=numel(LocsPerChannel{c}{np})/3; %number of localization in every NP (including the merged ones)
                    transLocs = LocsPerChannel{c}{np}.'; %Transpose matrix
                    FillFrames=transLocs(3,:) + 1; %frame number of localizations, adding 1 to correspond to the correct index (matlab counts from 1 and the frames start in 0...)
                    ParticleTimeTraces{np,c}(FillFrames)=1; %fill with 1s the index with a localization (bright time)
                    ParticleTimeTracesCut{np,c}=ParticleTimeTraces{np,c}((app.InitialframescutEditField.Value+1):end); % cut the initial frames
                    ParticleTimeTracesDark{np,c} = ParticleTimeTracesCut{np,c}(find(ParticleTimeTracesCut{np,c},1,'first'):find(ParticleTimeTracesCut{np,c},1,'last')); %remove leading and trailing zeros (dark) --> in this one you start and finish bright, then you count only the dark times you know the length for sure
                    ParticleTimeTracesBright{np,c} = ParticleTimeTracesCut{np,c}(find(~ParticleTimeTracesCut{np,c},1,'first'):find(~ParticleTimeTracesCut{np,c},1,'last')); %remove leading and trailing ones (bright) --> in this one you start and finish dark, then you count only the bright times you know the length for sure

                    % selection of consecutive zeros in the time-trace, corresponding to individual dark times:
                    difTrace=diff(ParticleTimeTracesDark{np,c}); %difference between the adjacent elements of the array (if they're equal is 0 but if it goes from bright, 1, to dark, 0, then is -1)
                    startInd=find(difTrace<0); % -1 means start of dark time (from bright(1) to dark(0))
                    endInd=find(difTrace>0); % 1 means end of dark time (from dark(0) to bright(1))
                    dark_times{np,c}=endInd-startInd; % cell array containing the individual dark times for each NP

                    % selection of consecutive ones in the time-trace corresponding to individual bright times:
                    difTrace=diff(ParticleTimeTracesBright{np,c}); %difference between the adjacent elements of the array (if they're equal is 0 but if it goes from dark, 0, to bright,10, then is 1)
                    startInd=find(difTrace>0); % -1 means start of dark time (from dark(0) to bright(1))
                    endInd=find(difTrace<0); % -1 means end of bright time (from bright(1) to dark(0))
                    bright_times{np,c}=endInd-startInd; % cell array containing the individual bright times for each NP

                    if  (isempty(dark_times{np,c})) || (length(dark_times{np,c})==1)||(isempty(bright_times{np,c})) || (length(bright_times{np,c})==1 || length(ParticleTimeTracesDark{np,c}) < (Nframes - app.InitialframescutEditField.Value)/2 * app.SpecificityFilterCheckBox.Value)

                        % if none or one dark/bright times, i.e. none or one localizations on the NP, statistics are set to zero.
                        % if the localizations only occur in half of the measurement, and the user selected the specificity filter checkbox, the statistics are set to zero too.
                        if length(ParticleTimeTracesDark{np,c}) < (Nframes - app.InitialframescutEditField.Value)/2
                            SpecificFiltCnt = SpecificFiltCnt + 1; %count the number of non-specific clusters
                        end
                        emptyNP=emptyNP+1; % count the NP without localizations
                        CDF{np,c}=0;
                    else

                        % Save length of the dark and bright times
                        lenght_bright(np,c) = length(bright_times{np,c});
                        length_dark(np,c) = length(dark_times{np,c});
                        % mean and median values for dark times:
                        dark_mean(np,c)=mean(dark_times{np,c});
                        dark_median(np,c)=median(dark_times{np,c});
                        % mean and median values for bright times:
                        bright_mean(np,c)=mean(bright_times{np,c});
                        bright_median(np,c)=median(bright_times{np,c});
                        % standard deviation for bright and dark times
                        sd_dark(np,c)=std(dark_times{np,c});
                        sd_bright(np,c)=std(bright_times{np,c});

                        % binning of individual dark times and distribution:
                        [~, binranges] = histcounts(dark_times{np,c},nbins); % binning of the individual dark times to get distribution --> bincounts == number of dark_times in each bin | binranges == The first vector element specifies the leading edge of the first bin. The last element specifies the trailing edge of the last bin. The trailing edge is only included for the last bin

                        % Empirical cumulative distribution of dark times (for plotting):
                        observations=dark_times{np,c}; %Get the dark times for each particle
                        observations=sort(observations); %Sort dark times low-high
                        tot_obs=length(observations); %get the number of dark times
                        CumDistrFun=zeros(length(binranges),2); %init array
                        CumDistrFun(:,1)= binranges; %Fill with the bin edges
                        for k1=1:length(binranges)
                            CumDistrFun(k1,2) = nnz(observations(:) <= binranges(k1)); % empirical cumulative distr function
                        end
                        CumDistrFun(:,2)=CumDistrFun(:,2)/tot_obs; % normalization
                        CDF{np,c}=CumDistrFun; % fill cell array with binning and empirical cumulative distribution of dark times for each NP

                        NormCumDistrFun=zeros(length(binranges),2);
                        NormCumDistrFun(:,1)=CumDistrFun(:,1)/norm(CumDistrFun(:,1)); % normalization
                        NormCumDistrFun(:,2)=CumDistrFun(:,2)/norm(CumDistrFun(:,2)); % normalization
                        NormCDF{np,c}=NormCumDistrFun; % fill cell array with binning and empirical cumulative distribution of dark times for each NP

                        % Calculate CDF, make a fit and find tau
                        try
                            [f,x] = ecdf(dark_times{np,c}); % make a CDF for the dark times

                            [fitted_curve,gof] = fit(x,f,fitfunCDF,'Lower', 1,'StartPoint', [dark_mean(np,c)]); % fit based on the exponential fit function
                            coeffs = coeffvalues(fitted_curve);
                            for k=1:numel(f)
                                if f(k)>0.9, break, end
                            end

                            if ((x(k) > x(end)/10) || (~OutlierFiltCheckbox))
                                dark_mean_fitted(np,c) = coeffs(1); % Tau is the first (and now only) coefficient of the fit
                                Td_fit_conf(np,c) = gof.rsquare; % obtain the rsquared for the goodness of fit
                            else
                                wrongShape=wrongShape+1;
                            end

                        catch
                            noCDF=noCDF+1; % count how many CDF's couldn't be fit
                        end

                        % Calculate target count
                        qPaintVals(np,c) = 1/((app.ExposuretimemsEditField.Value/1000)*dark_mean_fitted(np,c)*Kon(c).Value*Ci(c).Value);

                    end
                end
            end

            % plot all Dark times CDF shapes to see whether data is alligned
            figure('name',plus('normalized Dark times CDFs for file ', filename))
            hold on

            for c = 1:nChannels
                for np = 1:nNP
                    if (NormCDF{np,c} ~= 0)
                        plot(NormCDF{np,c}(:,1),NormCDF{np,c}(:,2),'Color',[1 0 0 .05]); %CDF
                    end
                end
            end

            drawnow
            hold off
            savefig(plus('results/normalized Dark times CDF by channel for file ', filename));

            % plot histogram in app
            histogram(dark_mean_fitted(:,1),nbins,'FaceColor','c','Parent', app.UIAxes7); %locs NP

            % remove all inf values from the qPAINT analysis
            qPaintVals(isinf(qPaintVals)) = 0;

            %append the qPAINT features to the matrix for the output file
            for i=1:nChannels
                if Checks(i).Value == 1
                    featureNames = [compose('channel %s true mean dark time', num2str(i));
                        compose('channel %s rSquared true mean dark time', num2str(i));
                        compose('channel %s number of bright time', num2str(i));
                        compose('channel %s number of dark time', num2str(i));
                        compose('channel %s mean dark time', num2str(i));
                        compose('channel %s median dark time', num2str(i));
                        compose('channel %s SD dark time', num2str(i));
                        compose('channel %s mean bright time', num2str(i));
                        compose('channel %s median bright time', num2str(i));
                        compose('channel %s SD bright time', num2str(i));
                        compose('channel %s target count', num2str(i))];
                    headers = [headers, featureNames'];

                    outm = [outm, dark_mean_fitted(:,i),Td_fit_conf(:,i), lenght_bright(:,i), length_dark(:,i), dark_mean(:,i),dark_median(:,i),sd_dark(:,i),bright_mean(:,i),bright_median(:,i),sd_bright(:,i),qPaintVals(:,i)];
                end
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton, RunButton_2
        function RunButtonPushed(app, event)
            tic
            diary logFile %Output command window messages into a log file
            mkdir results % Create folder to save the results
            silhouetteFlag = app.SilhouettemetricCheckBox.Value;
            if (app.ChannelAlignmentCheckBox.Value == 1) %This will read and correct the files sequentially
                channelAlignment(app); %call to channel alignment
                for n=1:app.numFiles.Value % For each different file that the user inputs
                    if app.numFiles.Value==1 % if there's only one file then it's a character vector (read all)
                        filePath = char(strcat(app.path.Value, app.file.Value)); %File name with path for matlab to find it in the file structure
                        filename = extractBefore(string(app.file.Value),'.'); %File name for the output file
                    else % if there's more than one file then it's a cell array (read only corresponding cell)
                        filePath = char(strcat(app.path.Value, app.file.Value(:,n)));
                        filename = extractBefore(string(app.file.Value(:,n)),'.');
                    end
                    app.dataMerge = [app.dataMerge;readFile(app, app.InputtypeDropDown.Value, filePath,n)];
                end
                disp(['Analizing file: ', filename]);
                [idx, sections] = clustering(app, filename, silhouetteFlag); % call to DBSCAN clustering
                [name_channels, locs4Clust_filtered, headers, outm] = qualityFilters(app,idx, sections, filename); % --> wrap into a parfor for each section
                if (app.qPaintCheckBox.Value == 1)
                    [headers, outm] = qpaint(app, name_channels, locs4Clust_filtered, filename, headers, outm);
                end
                % write output file
                outFilename = sprintf('results/features_%s.csv', filename);
                writematrix(headers, outFilename); % Initialize output csv file with headers
                writematrix(outm, outFilename, 'WriteMode', 'append');

                % If there's more than one file analysed, write the order of
                % the files to a txt file
                outFilename = sprintf('results/Channel_names_%s.txt',filename);
                if app.numFiles.Value > 1
                    writecell(app.file.Value,outFilename);
                end
                disp("all done!");
            else %This will cluster the files independently
                for n=1:app.numFiles.Value % For each different file that the user inputs
                    if app.numFiles.Value==1 % if there's only one file then it's a character vector (read all)
                        filePath = char(strcat(app.path.Value, app.file.Value)); %File name with path for matlab to find it in the file structure
                        filename = extractBefore(string(app.file.Value),'.'); %File name for the output file
                    else % if there's more than one file then it's a cell array (read only corresponding cell)
                        filePath = char(strcat(app.path.Value, app.file.Value(:,n)));
                        filename = extractBefore(string(app.file.Value(:,n)),'.');
                    end
                    app.dataMerge = readFile(app, app.InputtypeDropDown.Value, filePath,n); %Read file (if it's channel aligned it will read all sequentially)
                    disp(['Analizing file: ', filename]);
                    [idx, sections] = clustering(app, filename, silhouetteFlag); % call to DBSCAN clustering
                    [name_channels, locs4Clust_filtered, headers, outm] = qualityFilters(app,idx,sections,filename);
                    if (app.qPaintCheckBox.Value == 1)
                        [headers, outm] = qpaint(app, name_channels, locs4Clust_filtered, filename, headers, outm);
                    end
                    % write output file
                    outFilename = sprintf('results/features_%s.csv', filename);
                    writematrix(headers, outFilename); % Initialize output csv file with headers
                    writematrix(outm, outFilename, 'WriteMode', 'append');

                    % If there's more than one file analysed, write the order of
                    % the files to a txt file
                    outFilename = sprintf('results/Channel_names_%s.txt',filename);
                    if app.numFiles.Value > 1
                        writecell(app.file.Value,outFilename);
                    end
                    disp("all done!");
                end
            end
            toc
        end

        % Callback function: BrowseButton, InputfileEditField
        function InputfilepathEditFieldValueChanged(app, event)
            try
                [app.file.Value,app.path.Value] = uigetfile('*.*','Multiselect','on'); % add multiple files, char vector for 1 file, cell array for multiple
                drawnow();
                if app.ChannelAlignmentCheckBox.Value == 1
                    app.numFiles.Value = 1; % If we need to align channels, we will concatenate all files
                elseif iscellstr(app.file.Value) % if it's a cell array
                    app.numFiles.Value = length(app.file.Value); % user input multiple files
                    value = cast(app.file.Value(:,1),'char'); % cast to character vector to match type
                    app.InputfileEditField.Value = value; % draw filename in edit filed (only first file)
                else
                    app.numFiles.Value = 1; % user input only 1 file
                    app.InputfileEditField.Value = app.file.Value; % draw filename in edit filed
                end
            catch
                warning("No files selected");
                app.InputfileEditField.Value = ' '; % Clear text field
                drawnow();
            end

        end

        % Button pushed function: CloseButton, CloseButton_2, 
        % ...and 2 other components
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Value changed function: ScanningdiameternmEditField
        function ScanningdiameternmEditFieldValueChanged(app, event)
            app.ScanningdiameternmEditField.Value;
        end

        % Value changed function: MinimumpointsEditField
        function MinimumpointsEditFieldValueChanged(app, event)
            app.MinimumpointsEditField.Value;
        end

        % Value changed function: MaximumaspectratioEditField
        function MaximumaspectratioEditFieldValueChanged(app, event)
            app.MaximumaspectratioEditField.Value;
        end

        % Value changed function: DesiredaspectratioEditField
        function DesiredaspectratioEditFieldValueChanged(app, event)
            app.DesiredaspectratioEditField.Value;
        end

        % Value changed function: MininterclusterdistancenmEditField
        function MininterclusterdistancenmEditFieldValueChanged(app, event)
            app.MininterclusterdistancenmEditField.Value;
        end

        % Value changed function: InputtypeDropDown
        function InputtypeDropDownValueChanged(app, event)
            app.InputtypeDropDown.Value;
        end

        % Value changed function: RadiusthresholdEditField
        function RadiusthresholdEditFieldValueChanged(app, event)
            app.RadiusthresholdEditField.Value;
        end

        % Value changed function: RadiuslowlimitnmEditField
        function RadiuslowlimitnmEditFieldValueChanged(app, event)
            app.RadiuslowlimitnmEditField.Value;
        end

        % Value changed function: RadiushighlimitnmEditField
        function RadiushighlimitnmEditFieldValueChanged(app, event)
            app.RadiushighlimitnmEditField.Value;
        end

        % Value changed function: MaximumpointsEditField
        function MaximumpointsEditFieldValueChanged(app, event)
            app.MaximumpointsEditField.Value;
        end

        % Value changed function: ChannelAlignmentCheckBox
        function ChannelAlignmentCheckBoxValueChanged(app, event)
            app.ChannelAlignmentCheckBox.Value;
        end

        % Value changed function: SilhouettemetricCheckBox
        function SilhouettemetricCheckBoxValueChanged(app, event)
            app.SilhouettemetricCheckBox.Value;
        end

        % Value changed function: qPaintCheckBox
        function qPaintCheckBoxValueChanged(app, event)
            if app.qPaintCheckBox.Value == true
                set(app.OptionsPanel,'ToolTip','')
                set(app.OptionsPanel,'Enable', 'on')
                set(app.GiveinformationperchannelPanel,'ToolTip','')
                set(app.GiveinformationperchannelPanel,'Enable', 'on')
            else
                set(app.OptionsPanel,'ToolTip','Toggle qPAINT in filters menu to perform qPAINT analysis')
                set(app.OptionsPanel,'Enable', 'off')
                set(app.GiveinformationperchannelPanel,'ToolTip','Toggle qPAINT in filters menu to perform qPAINT analysis')
                set(app.GiveinformationperchannelPanel,'Enable', 'off')
            end
        end

        % Value changed function: CheckBoxChannel1, CheckBoxChannel2, 
        % ...and 7 other components
        function NumberofchannelsEditFieldValueChanged(app, event)
            Checks = [app.CheckBoxChannel1,app.CheckBoxChannel2,app.CheckBoxChannel3,app.CheckBoxChannel4,app.CheckBoxChannel5,app.CheckBoxChannel6,app.CheckBoxChannel7,app.CheckBoxChannel8,app.CheckBoxChannel9,app.CheckBoxChannel10,app.CheckBoxChannel11,app.CheckBoxChannel12];
            Ci = [app.Channel1EditField,app.Channel2EditField,app.Channel3EditField,app.Channel4EditField,app.Channel5EditField,app.Channel6EditField,app.Channel7EditField,app.Channel8EditField,app.Channel9EditField,app.Channel10EditField,app.Channel11EditField,app.Channel12EditField];
            Kon = [app.Channel1bEditField,app.Channel2bEditField,app.Channel3bEditField,app.Channel4bEditField,app.Channel5bEditField,app.Channel6bEditField,app.Channel7bEditField,app.Channel8bEditField,app.Channel9bEditField,app.Channel10bEditField,app.Channel11bEditField,app.Channel12bEditField];
            Labels = [app.Channel1EditFieldLabel,app.Channel2EditFieldLabel,app.Channel3EditFieldLabel,app.Channel4EditFieldLabel,app.Channel5EditFieldLabel,app.Channel6EditFieldLabel,app.Channel7EditFieldLabel,app.Channel8EditFieldLabel,app.Channel9EditFieldLabel,app.Channel10EditFieldLabel,app.Channel11EditFieldLabel,app.Channel12EditFieldLabel];

            NumOfChannels = app.NumberofchannelsEditField.Value;

            for i=1:length(Checks)
                set(Checks(i),'Visible',false)
                set(Ci(i),'Visible',false)
                set(Kon(i),'Visible',false)
                set(Labels(i),'Visible',false)
                set(Ci(i),'Enable',false)
                set(Kon(i),'Enable',false)
            end

            for i=1:NumOfChannels
                set(Checks(i),'Visible','on')
                set(Ci(i),'Visible','on')
                set(Kon(i),'Visible','on')
                set(Labels(i),'Visible','on')
                if Checks(i).Value == true
                    set(Ci(i),'Enable','on')
                    set(Kon(i),'Enable','on')
                end
            end


        end

        % Value changed function: SpecificityFilterCheckBox
        function SpecificityFilterCheckBoxValueChanged(app, event)
            value = app.SpecificityFilterCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.InputfileEditField_5Label = uilabel(app.FiltersTab);
            app.InputfileEditField_5Label.Text = 'Input file:';
            app.InputfileEditField = uieditfield(app.FiltersTab, 'text');
            app.InputfileEditField.ValueChangedFcn = createCallbackFcn(app, @InputfilepathEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.FiltersTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @InputfilepathEditFieldValueChanged, true);
            app.BrowseButton.Text = 'Browse';
            app.InputtypeLabel = uilabel(app.FiltersTab);
            app.InputtypeLabel.Text = 'Input type:';
            app.InputtypeDropDown = uidropdown(app.FiltersTab);
            app.InputtypeDropDown.Items = {'ONI', 'NIKON', 'ThunderSTORM'};
            app.InputtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputtypeDropDownValueChanged, true);
            app.InputtypeDropDown.Value = 'ONI';
            app.FiltersPanel = uipanel(app.FiltersTab);
            app.FiltersPanel.Title = 'Filters';
            app.ChannelAlignmentCheckBox = uicheckbox(app.FiltersPanel);
            app.ChannelAlignmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChannelAlignmentCheckBoxValueChanged, true);
            app.ChannelAlignmentCheckBox.Text = 'Channel alignment';
            app.ExtraanalysisPanel = uipanel(app.FiltersTab);
            app.ExtraanalysisPanel.Title = 'Extra analysis';
            app.SilhouettemetricCheckBox = uicheckbox(app.ExtraanalysisPanel);
            app.SilhouettemetricCheckBox.ValueChangedFcn = createCallbackFcn(app, @SilhouettemetricCheckBoxValueChanged, true);
            app.SilhouettemetricCheckBox.Text = 'Silhouette metric';
            app.qPaintCheckBox = uicheckbox(app.ExtraanalysisPanel);
            app.qPaintCheckBox.ValueChangedFcn = createCallbackFcn(app, @qPaintCheckBoxValueChanged, true);
            app.qPaintCheckBox.Text = 'qPAINT';
            app.CloseButton = uibutton(app.FiltersTab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.DBSCANClusteringPanel = uipanel(app.ParametersTab);
            app.DBSCANClusteringPanel.Title = 'DBSCAN Clustering';
            app.ScanningdiameternmEditFieldLabel = uilabel(app.DBSCANClusteringPanel);
            app.ScanningdiameternmEditFieldLabel.Text = 'Scanning diameter (nm)';
            app.ScanningdiameternmEditField = uieditfield(app.DBSCANClusteringPanel, 'numeric');
            app.ScanningdiameternmEditField.Limits = [0 Inf];
            app.ScanningdiameternmEditField.ValueChangedFcn = createCallbackFcn(app, @ScanningdiameternmEditFieldValueChanged, true);
            app.ScanningdiameternmEditField.Value = 50;
            app.MinimumpointsEditFieldLabel = uilabel(app.DBSCANClusteringPanel);
            app.MinimumpointsEditFieldLabel.Text = 'Minimum points';
            app.MinimumpointsEditField = uieditfield(app.DBSCANClusteringPanel, 'numeric');
            app.MinimumpointsEditField.Limits = [0 Inf];
            app.MinimumpointsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumpointsEditFieldValueChanged, true);
            app.MinimumpointsEditField.Value = 300;
            app.ParticlesizecheckPanel = uipanel(app.ParametersTab);
            app.ParticlesizecheckPanel.Title = 'Particle size check';
            app.RadiuslowlimitnmEditFieldLabel = uilabel(app.ParticlesizecheckPanel);
            app.RadiuslowlimitnmEditFieldLabel.Text = 'Radius low limit (nm)';
            app.RadiuslowlimitnmEditField = uieditfield(app.ParticlesizecheckPanel, 'numeric');
            app.RadiuslowlimitnmEditField.Limits = [0 Inf];
            app.RadiuslowlimitnmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiuslowlimitnmEditFieldValueChanged, true);
            app.RadiuslowlimitnmEditField.Value = 30;
            app.RadiushighlimitnmEditFieldLabel = uilabel(app.ParticlesizecheckPanel);
            app.RadiushighlimitnmEditFieldLabel.Text = 'Radius high limit (nm)';
            app.RadiushighlimitnmEditField = uieditfield(app.ParticlesizecheckPanel, 'numeric');
            app.RadiushighlimitnmEditField.Limits = [0 Inf];
            app.RadiushighlimitnmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiushighlimitnmEditFieldValueChanged, true);
            app.RadiushighlimitnmEditField.Value = 100;
            app.RadiusthresholdEditFieldLabel = uilabel(app.ParticlesizecheckPanel);
            app.RadiusthresholdEditFieldLabel.Text = 'Radius threshold';
            app.RadiusthresholdEditField = uieditfield(app.ParticlesizecheckPanel, 'numeric');
            app.RadiusthresholdEditField.Limits = [0 Inf];
            app.RadiusthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusthresholdEditFieldValueChanged, true);
            app.RadiusthresholdEditField.Value = 0.9;
            app.FractionoflocalizationswithinfittedsphereLabel = uilabel(app.ParticlesizecheckPanel);
            app.FractionoflocalizationswithinfittedsphereLabel.Text = 'Fraction of localizations within fitted sphere';
            app.FilteringPanel = uipanel(app.ParametersTab);
            app.FilteringPanel.Title = 'Filtering';
            app.MaximumaspectratioEditFieldLabel = uilabel(app.FilteringPanel);
            app.MaximumaspectratioEditFieldLabel.Text = 'Maximum aspect ratio';
            app.MaximumaspectratioEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.MaximumaspectratioEditField.Limits = [0 Inf];
            app.MaximumaspectratioEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumaspectratioEditFieldValueChanged, true);
            app.MaximumaspectratioEditField.Value = 2;
            app.AspectratioLabel = uilabel(app.FilteringPanel);
            app.AspectratioLabel.Text = 'Desired aspect ratio';
            app.DesiredaspectratioEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.DesiredaspectratioEditField.Limits = [0 10];
            app.DesiredaspectratioEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredaspectratioEditFieldValueChanged, true);
            app.DesiredaspectratioEditField.Value = 1;
            app.MininterclusterdistancenmEditFieldLabel = uilabel(app.FilteringPanel);
            app.MininterclusterdistancenmEditFieldLabel.Text = 'Min. inter-cluster distance (nm)';
            app.MininterclusterdistancenmEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.MininterclusterdistancenmEditField.Limits = [0 Inf];
            app.MininterclusterdistancenmEditField.ValueChangedFcn = createCallbackFcn(app, @MininterclusterdistancenmEditFieldValueChanged, true);
            app.MininterclusterdistancenmEditField.Value = 300;
            app.MaximumpointsEditFieldLabel = uilabel(app.FilteringPanel);
            app.MaximumpointsEditFieldLabel.Text = 'Maximum points';
            app.MaximumpointsEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.MaximumpointsEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumpointsEditFieldValueChanged, true);
            app.MaximumpointsEditField.Value = Inf;
            app.FiducialalignmentPanel = uipanel(app.ParametersTab);
            app.FiducialalignmentPanel.Title = 'Fiducial alignment';
            app.MaximuminterfiducialdistancenmEditFieldLabel = uilabel(app.FiducialalignmentPanel);
            app.MaximuminterfiducialdistancenmEditFieldLabel.Text = {'Maximum inter-'; 'fiducial distance (nm)'};
            app.MaximuminterfiducialdistancenmEditField = uieditfield(app.FiducialalignmentPanel, 'numeric');
            app.MaximuminterfiducialdistancenmEditField.Limits = [0 Inf];
            app.MaximuminterfiducialdistancenmEditField.Value = 500;
            app.CloseButton_2 = uibutton(app.ParametersTab, 'push');
            app.CloseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton_2.Text = 'Close';
            app.RunButton = uibutton(app.ParametersTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.qPAINTTab = uitab(app.TabGroup);
            app.qPAINTTab.Title = 'qPAINT';
            app.OptionsPanel = uipanel(app.qPAINTTab);
            app.OptionsPanel.Tooltip = {'Toggle qPAINT in filters menu to perform qPAINT analysis'};
            app.OptionsPanel.Enable = 'off';
            app.OptionsPanel.Title = 'Options';
            app.SpecificityFilterCheckBox = uicheckbox(app.OptionsPanel);
            app.SpecificityFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpecificityFilterCheckBoxValueChanged, true);
            app.SpecificityFilterCheckBox.Text = 'Filter non-specific clusters';
            app.InitialframescutEditFieldLabel = uilabel(app.OptionsPanel);
            app.InitialframescutEditFieldLabel.Text = 'Initial frames cut';
            app.InitialframescutEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.InitialframescutEditField.Limits = [0 Inf];
            app.FramesthresholdtomergeEditFieldLabel = uilabel(app.OptionsPanel);
            app.FramesthresholdtomergeEditFieldLabel.Text = 'Frames threshold to merge';
            app.FramesthresholdtomergeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.FramesthresholdtomergeEditField.Limits = [0 Inf];
            app.FramesthresholdtomergeEditField.Value = 1;
            app.NumberofchannelsEditFieldLabel = uilabel(app.OptionsPanel);
            app.NumberofchannelsEditFieldLabel.Text = 'Number of channels';
            app.NumberofchannelsEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.NumberofchannelsEditField.Limits = [1 12];
            app.NumberofchannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.NumberofchannelsEditField.Value = 1;
            app.ExposuretimemsEditFieldLabel = uilabel(app.OptionsPanel);
            app.ExposuretimemsEditFieldLabel.Text = 'Exposure time (ms)';
            app.ExposuretimemsEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.ExposuretimemsEditField.Limits = [0 Inf];
            app.ExposuretimemsEditField.Value = 50;
            app.GiveinformationperchannelPanel = uipanel(app.qPAINTTab);
            app.GiveinformationperchannelPanel.Tooltip = {'Toggle qPAINT in filters menu to perform qPAINT analysis'};
            app.GiveinformationperchannelPanel.Enable = 'off';
            app.GiveinformationperchannelPanel.Title = 'Give information per channel';
            app.GridLayout = uigridlayout(app.GiveinformationperchannelPanel);
            app.K_onM1S1Label = uilabel(app.GridLayout);
            app.K_onM1S1Label.Text = '$K_{on}$ $(M^{-1} S^{-1})$';
            app.C_iMLabel = uilabel(app.GridLayout);
            app.C_iMLabel.Text = '$C_i$ $(M)$';
            app.Channel1EditFieldLabel = uilabel(app.GridLayout);
            app.Channel1EditFieldLabel.Text = 'Channel 1';
            app.CheckBoxChannel1 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel1.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.CheckBoxChannel1.Text = '';
            app.CheckBoxChannel1.Value = true;
            app.Channel1bEditFieldLabel = uilabel(app.GridLayout);
            app.Channel1bEditFieldLabel.Enable = 'off';
            app.Channel1bEditFieldLabel.Visible = 'off';
            app.Channel1bEditFieldLabel.Text = 'Channel 1b';
            app.Channel1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel1EditField.Limits = [0 Inf];
            app.Channel1EditField.Value = 7800000;
            app.Channel1bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel1bEditField.Limits = [0 Inf];
            app.Channel1bEditField.Value = 1e-09;
            app.Channel2EditFieldLabel = uilabel(app.GridLayout);
            app.Channel2EditFieldLabel.Visible = 'off';
            app.Channel2EditFieldLabel.Text = 'Channel 2';
            app.CheckBoxChannel2 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel2.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.CheckBoxChannel2.Visible = 'off';
            app.CheckBoxChannel2.Text = '';
            app.CheckBoxChannel2.Value = true;
            app.Channel2bLabel = uilabel(app.GridLayout);
            app.Channel2bLabel.Enable = 'off';
            app.Channel2bLabel.Visible = 'off';
            app.Channel2bLabel.Text = 'Channel 2b';
            app.Channel2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel2EditField.Limits = [0 Inf];
            app.Channel2EditField.Visible = 'off';
            app.Channel2EditField.Value = 9300000;
            app.Channel2bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel2bEditField.Limits = [0 Inf];
            app.Channel2bEditField.Visible = 'off';
            app.Channel2bEditField.Value = 1e-09;
            app.Channel3EditFieldLabel = uilabel(app.GridLayout);
            app.Channel3EditFieldLabel.Visible = 'off';
            app.Channel3EditFieldLabel.Text = 'Channel 3';
            app.CheckBoxChannel3 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel3.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.CheckBoxChannel3.Visible = 'off';
            app.CheckBoxChannel3.Text = '';
            app.CheckBoxChannel3.Value = true;
            app.Channel3EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel3EditField.Limits = [0 Inf];
            app.Channel3EditField.Visible = 'off';
            app.Channel3EditField.Value = 5500000;
            app.Channel3bLabel = uilabel(app.GridLayout);
            app.Channel3bLabel.Enable = 'off';
            app.Channel3bLabel.Visible = 'off';
            app.Channel3bLabel.Text = 'Channel 3b';
            app.Channel3bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel3bEditField.Limits = [0 Inf];
            app.Channel3bEditField.Visible = 'off';
            app.Channel3bEditField.Value = 1e-09;
            app.Channel4EditFieldLabel = uilabel(app.GridLayout);
            app.Channel4EditFieldLabel.Visible = 'off';
            app.Channel4EditFieldLabel.Text = 'Channel 4';
            app.CheckBoxChannel4 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel4.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.CheckBoxChannel4.Visible = 'off';
            app.CheckBoxChannel4.Text = '';
            app.CheckBoxChannel4.Value = true;
            app.Channel4EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel4EditField.Limits = [0 Inf];
            app.Channel4EditField.Visible = 'off';
            app.Channel4bLabel = uilabel(app.GridLayout);
            app.Channel4bLabel.Enable = 'off';
            app.Channel4bLabel.Visible = 'off';
            app.Channel4bLabel.Text = 'Channel 4b';
            app.Channel4bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel4bEditField.Limits = [0 Inf];
            app.Channel4bEditField.Visible = 'off';
            app.Channel5EditFieldLabel = uilabel(app.GridLayout);
            app.Channel5EditFieldLabel.Visible = 'off';
            app.Channel5EditFieldLabel.Text = 'Channel 5';
            app.CheckBoxChannel5 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel5.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.CheckBoxChannel5.Visible = 'off';
            app.CheckBoxChannel5.Text = '';
            app.CheckBoxChannel5.Value = true;
            app.Channel5EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel5EditField.Limits = [0 Inf];
            app.Channel5EditField.Visible = 'off';
            app.Channel1bEditField_3Label_2 = uilabel(app.GridLayout);
            app.Channel1bEditField_3Label_2.Enable = 'off';
            app.Channel1bEditField_3Label_2.Visible = 'off';
            app.Channel1bEditField_3Label_2.Text = 'Channel 5b';
            app.Channel5bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel5bEditField.Limits = [0 Inf];
            app.Channel5bEditField.Visible = 'off';
            app.Channel6EditFieldLabel = uilabel(app.GridLayout);
            app.Channel6EditFieldLabel.Visible = 'off';
            app.Channel6EditFieldLabel.Text = 'Channel 6';
            app.CheckBoxChannel6 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel6.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.CheckBoxChannel6.Visible = 'off';
            app.CheckBoxChannel6.Text = '';
            app.CheckBoxChannel6.Value = true;
            app.Channel6EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel6EditField.Limits = [0 Inf];
            app.Channel6EditField.Visible = 'off';
            app.Channel1bEditField_3Label_3 = uilabel(app.GridLayout);
            app.Channel1bEditField_3Label_3.Enable = 'off';
            app.Channel1bEditField_3Label_3.Visible = 'off';
            app.Channel1bEditField_3Label_3.Text = 'Channel 6b';
            app.Channel6bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel6bEditField.Limits = [0 Inf];
            app.Channel6bEditField.Visible = 'off';
            app.Channel7EditFieldLabel = uilabel(app.GridLayout);
            app.Channel7EditFieldLabel.Visible = 'off';
            app.Channel7EditFieldLabel.Text = 'Channel 7';
            app.CheckBoxChannel7 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel7.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.CheckBoxChannel7.Visible = 'off';
            app.CheckBoxChannel7.Text = '';
            app.CheckBoxChannel7.Value = true;
            app.Channel7EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel7EditField.Limits = [0 Inf];
            app.Channel7EditField.Visible = 'off';
            app.Channel1bEditField_3Label_4 = uilabel(app.GridLayout);
            app.Channel1bEditField_3Label_4.Enable = 'off';
            app.Channel1bEditField_3Label_4.Visible = 'off';
            app.Channel1bEditField_3Label_4.Text = 'Channel 7b';
            app.Channel7bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel7bEditField.Limits = [0 Inf];
            app.Channel7bEditField.Visible = 'off';
            app.Channel8EditFieldLabel = uilabel(app.GridLayout);
            app.Channel8EditFieldLabel.Visible = 'off';
            app.Channel8EditFieldLabel.Text = 'Channel 8';
            app.CheckBoxChannel8 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel8.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEditFieldValueChanged, true);
            app.CheckBoxChannel8.Visible = 'off';
            app.CheckBoxChannel8.Text = '';
            app.CheckBoxChannel8.Value = true;
            app.Channel8EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel8EditField.Limits = [0 Inf];
            app.Channel8EditField.Visible = 'off';
            app.Channel1bEditField_3Label_5 = uilabel(app.GridLayout);
            app.Channel1bEditField_3Label_5.Enable = 'off';
            app.Channel1bEditField_3Label_5.Visible = 'off';
            app.Channel1bEditField_3Label_5.Text = 'Channel 8b';
            app.Channel8bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel8bEditField.Limits = [0 Inf];
            app.Channel8bEditField.Visible = 'off';
            app.Channel9EditFieldLabel = uilabel(app.GridLayout);
            app.Channel9EditFieldLabel.Visible = 'off';
            app.Channel9EditFieldLabel.Text = 'Channel 9';
            app.CheckBoxChannel9 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel9.Visible = 'off';
            app.CheckBoxChannel9.Text = '';
            app.CheckBoxChannel9.Value = true;
            app.Channel9EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel9EditField.Limits = [0 Inf];
            app.Channel9EditField.Visible = 'off';
            app.Channel1bEditField_2Label_2 = uilabel(app.GridLayout);
            app.Channel1bEditField_2Label_2.Enable = 'off';
            app.Channel1bEditField_2Label_2.Visible = 'off';
            app.Channel1bEditField_2Label_2.Text = 'Channel 9b';
            app.Channel9bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel9bEditField.Limits = [0 Inf];
            app.Channel9bEditField.Visible = 'off';
            app.Channel10EditFieldLabel = uilabel(app.GridLayout);
            app.Channel10EditFieldLabel.Visible = 'off';
            app.Channel10EditFieldLabel.Text = 'Channel 10';
            app.CheckBoxChannel10 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel10.Visible = 'off';
            app.CheckBoxChannel10.Text = '';
            app.CheckBoxChannel10.Value = true;
            app.Channel10EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel10EditField.Limits = [0 Inf];
            app.Channel10EditField.Visible = 'off';
            app.Channel1bEditField_2Label_3 = uilabel(app.GridLayout);
            app.Channel1bEditField_2Label_3.Enable = 'off';
            app.Channel1bEditField_2Label_3.Visible = 'off';
            app.Channel1bEditField_2Label_3.Text = 'Channel 10b';
            app.Channel10bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel10bEditField.Limits = [0 Inf];
            app.Channel10bEditField.Visible = 'off';
            app.Channel11EditFieldLabel = uilabel(app.GridLayout);
            app.Channel11EditFieldLabel.Visible = 'off';
            app.Channel11EditFieldLabel.Text = 'Channel 11';
            app.CheckBoxChannel11 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel11.Visible = 'off';
            app.CheckBoxChannel11.Text = '';
            app.CheckBoxChannel11.Value = true;
            app.Channel11EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel11EditField.Limits = [0 Inf];
            app.Channel11EditField.Visible = 'off';
            app.Channel11bEditFieldLabel = uilabel(app.GridLayout);
            app.Channel11bEditFieldLabel.Enable = 'off';
            app.Channel11bEditFieldLabel.Visible = 'off';
            app.Channel11bEditFieldLabel.Text = 'Channel 11b';
            app.Channel11bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel11bEditField.Limits = [0 Inf];
            app.Channel11bEditField.Visible = 'off';
            app.Channel12EditFieldLabel = uilabel(app.GridLayout);
            app.Channel12EditFieldLabel.Visible = 'off';
            app.Channel12EditFieldLabel.Text = 'Channel 12';
            app.CheckBoxChannel12 = uicheckbox(app.GridLayout);
            app.CheckBoxChannel12.Visible = 'off';
            app.CheckBoxChannel12.Text = '';
            app.CheckBoxChannel12.Value = true;
            app.Channel12EditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel12EditField.Limits = [0 Inf];
            app.Channel12EditField.Visible = 'off';
            app.Channel12bEditFieldLabel = uilabel(app.GridLayout);
            app.Channel12bEditFieldLabel.Enable = 'off';
            app.Channel12bEditFieldLabel.Visible = 'off';
            app.Channel12bEditFieldLabel.Text = 'Channel 12b';
            app.Channel12bEditField = uieditfield(app.GridLayout, 'numeric');
            app.Channel12bEditField.Limits = [0 Inf];
            app.Channel12bEditField.Visible = 'off';
            app.CloseButton_4 = uibutton(app.qPAINTTab, 'push');
            app.CloseButton_4.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton_4.Text = 'Close';
            app.RunButton_2 = uibutton(app.qPAINTTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton_2.Text = 'Run';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.TabGroup2 = uitabgroup(app.GraphsTab);
            app.LocNPTab = uitab(app.TabGroup2);
            app.LocNPTab.Title = 'Loc/NP';
            app.UIAxes4 = uiaxes(app.LocNPTab);
            title(app.UIAxes4, 'Localizations/Nanoparticle')
            xlabel(app.UIAxes4, 'Number of localizations')
            ylabel(app.UIAxes4, 'Count')
            colormap(app.UIAxes4, 'cool')
            app.NPdiamTab = uitab(app.TabGroup2);
            app.NPdiamTab.Title = 'NP diam';
            app.UIAxes5 = uiaxes(app.NPdiamTab);
            title(app.UIAxes5, 'Nanoparticle diameter')
            xlabel(app.UIAxes5, 'Diameter (nm)')
            ylabel(app.UIAxes5, 'Count')
            colormap(app.UIAxes5, 'cool')
            app.DiamlocsTab = uitab(app.TabGroup2);
            app.DiamlocsTab.Title = 'Diam/locs';
            app.UIAxes6 = uiaxes(app.DiamlocsTab);
            title(app.UIAxes6, 'Diameter vs localizations')
            xlabel(app.UIAxes6, 'Diameter (nm)')
            ylabel(app.UIAxes6, 'Number of localizations')
            colormap(app.UIAxes6, 'cool')
            app.NPTdTab = uitab(app.TabGroup2);
            app.NPTdTab.Title = 'NP Td';
            app.UIAxes7 = uiaxes(app.NPTdTab);
            title(app.UIAxes7, 'Nanoparticle Fitted mean dark time')
            xlabel(app.UIAxes7, 'Td')
            ylabel(app.UIAxes7, 'Count')
            colormap(app.UIAxes7, 'cool')
            app.CloseButton_3 = uibutton(app.GraphsTab, 'push');
            app.CloseButton_3.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton_3.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nanoFeatures

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end